classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image                           matlab.ui.control.Image
        Image_3                         matlab.ui.control.Image
        InputPanel                      matlab.ui.container.Panel
        fat_expansioninlitersEditFieldLabel  matlab.ui.control.Label
        fat_expansioninlitersEditField  matlab.ui.control.NumericEditField
        ratioorganmuscleEditFieldLabel  matlab.ui.control.Label
        ratioorganmuscleEditField       matlab.ui.control.NumericEditField
        runButton                       matlab.ui.control.Button
        TextArea_3                      matlab.ui.control.TextArea
        TextArea_4                      matlab.ui.control.TextArea
        TextArea_5                      matlab.ui.control.TextArea
        TextArea                        matlab.ui.control.TextArea
        TextArea_2                      matlab.ui.control.TextArea
        SliderLabel                     matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        slicenoEditFieldLabel           matlab.ui.control.Label
        slicenoEditField                matlab.ui.control.NumericEditField
        selectdirectionButtonGroup      matlab.ui.container.ButtonGroup
        TButton                         matlab.ui.control.RadioButton
        SButton                         matlab.ui.control.RadioButton
        CButton                         matlab.ui.control.RadioButton
        Image2                          matlab.ui.control.Image
        directionEditFieldLabel         matlab.ui.control.Label
        directionEditField              matlab.ui.control.EditField
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, number)
            
        end

        % Button pushed function: runButton
        function run_button(app, event)
            app.TextArea_4.Visible = 'on';
            app.Image.ImageSource = '';
%             n = app.slicenoEditFieldLabel.Value;

            exl = app.fat_expansioninlitersEditField.Value;
            r = app.ratioorganmuscleEditField.Value;
            if exl == 2 && r == 2
                processed_img = load('final_img_22_2D.mat').final_img;
            else
                get_distinguished_data();
%                 vis_fat_wall_no;
                processed_img = save3d_final(exl,r);%%%%%%%*************************************************!
            end
            processed_img = reshape(processed_img,[490,500,265]);
            save('ALL_PROCESSED_DATA.mat',"processed_img");
            
            app.TextArea_5.Visible = 'on';
            %% the code below this line in current function is org
%             n = 1;
%             exl = app.fat_expansioninlitersEditField.Value;
%             r = app.ratioorganmuscleEditField.Value;
%             run_my(n,exl,r);
% %             app.Image.ImageSource = 'me.png';
%             org = imread('original_image.jpg');
%             app.Image_3.ImageSource = repmat(org,1,1,3);
%             im = imread('cur_slice.jpg');
%             app.Image.ImageSource = repmat(im,1,1,3);   % use im=imread and repmat to update the image.
%             
%             % make image and bar visualized
%             app.Image_3.Visible = 'on';
%             app.Image.Visible = 'on';
%             app.TextArea.Visible = 'on';
%             app.TextArea_2.Visible = 'on';
        end

        % Value changed function: Slider
        function slice(app, event)
            all_processed_data = load('ALL_PROCESSED_DATA.mat').processed_img;
            all_org_data = load('distinguish_all.mat').dist_all;
            all_org_3d = reshape(all_org_data,[490,500,265]);
            all_org_3d = all_org_3d./80;
            
            select_button = app.selectdirectionButtonGroup.SelectedObject.Text;
            
            switch select_button
                case 'T'
                    n = 501-round(app.Slider.Value);
                    p = all_processed_data(:,n,:);
                    pp = reshape(p,[490,265]);
                    o = all_org_3d(:,n,:);
                    oo = reshape(o,[490,265]);
                case 'S'
                    n = 491-round(app.Slider.Value);
                    p = all_processed_data(n,:,:);
                    pp = reshape(p,[500,265]);
                    o = all_org_3d(n,:,:);
                    oo = reshape(o,[500,265]);
                case 'C'
                    n = 266-round(app.Slider.Value);
                    p = all_processed_data(:,:,n);
                    ppp = reshape(p,[490,500]);
                    o = all_org_3d(:,:,n);
                    ooo = reshape(o,[490,500]);
                    pp = rot90(ppp,3);
                    oo = rot90(ooo,3);
            end
            
            imwrite(pp,'processed_slice.jpg')
            imwrite(oo,'org_slice.jpg')
            cur = imread('processed_slice.jpg');
            app.Image.ImageSource = repmat(cur,1,1,3);
            org = imread('org_slice.jpg');
            app.Image_3.ImageSource = repmat(org,1,1,3); 
%             
%             app.Image.ImageSource = 'processed_slice.jpg';
            % make image and bar visualized
            app.Image_3.Visible = 'on';
            app.Image.Visible = 'on';
            app.TextArea.Visible = 'on';
            app.TextArea_2.Visible = 'on';
            
            app.slicenoEditField.Value = n;
        end

        % Callback function
        function select(app, event)
            
        end

        % Selection changed function: selectdirectionButtonGroup
        function TSC(app, event)
            selectedButton = app.selectdirectionButtonGroup.SelectedObject;
%             app.selectdirectionButtonGroup.ButtonDownFcn()
            switch selectedButton.Text
                case 'T'
                    app.Slider.Limits = [1,500];
                    app.directionEditField.Value = '   T';      % 3 spaces make good like lol
                case 'C'
                    app.Slider.Limits = [1,265];
                    app.directionEditField.Value = '   C';
                case 'S'
                    app.Slider.Limits = [1,490];
                    app.directionEditField.Value = '   S';
            end
%             app.slicenoEditField.Value = app.SButton
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.Tag = 'figure2';
            app.Image.Visible = 'off';
            app.Image_3 = uiimage(app.UIFigure);
            app.Image_3.Tag = 'figure2';
            app.Image_3.Visible = 'off';
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input';
            app.fat_expansioninlitersEditFieldLabel = uilabel(app.InputPanel);
            app.fat_expansioninlitersEditFieldLabel.Text = 'fat_expansion(in liters)';
            app.fat_expansioninlitersEditField = uieditfield(app.InputPanel, 'numeric');
            app.fat_expansioninlitersEditField.Value = 2;
            app.ratioorganmuscleEditFieldLabel = uilabel(app.InputPanel);
            app.ratioorganmuscleEditFieldLabel.Text = 'ratio (organ/muscle)';
            app.ratioorganmuscleEditField = uieditfield(app.InputPanel, 'numeric');
            app.ratioorganmuscleEditField.Tag = 'number';
            app.ratioorganmuscleEditField.Value = 2;
            app.runButton = uibutton(app.InputPanel, 'push');
            app.runButton.ButtonPushedFcn = createCallbackFcn(app, @run_button, true);
            app.runButton.Tag = 'run';
            app.runButton.Text = 'run';
            app.TextArea_3 = uitextarea(app.InputPanel);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'       Processing Status:'};
            app.TextArea_4 = uitextarea(app.InputPanel);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Visible = 'off';
            app.TextArea_4.Value = {'Processing......'};
            app.TextArea_5 = uitextarea(app.InputPanel);
            app.TextArea_5.Editable = 'off';
            app.TextArea_5.Visible = 'off';
            app.TextArea_5.Value = {'Data Ready!'};
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Visible = 'off';
            app.TextArea.Value = {'Original Image'};
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Visible = 'off';
            app.TextArea_2.Value = {'Img_after_exp'};
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [1 500];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @slice, true);
            app.Slider.Value = 1;
            app.slicenoEditFieldLabel = uilabel(app.UIFigure);
            app.slicenoEditFieldLabel.Text = 'slice no.';
            app.slicenoEditField = uieditfield(app.UIFigure, 'numeric');
            app.slicenoEditField.Value = 1;
            app.selectdirectionButtonGroup = uibuttongroup(app.UIFigure);
            app.selectdirectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TSC, true);
            app.selectdirectionButtonGroup.Title = 'select direction';
            app.TButton = uiradiobutton(app.selectdirectionButtonGroup);
            app.TButton.Text = 'T';
            app.TButton.Value = true;
            app.SButton = uiradiobutton(app.selectdirectionButtonGroup);
            app.SButton.Text = 'S';
            app.CButton = uiradiobutton(app.selectdirectionButtonGroup);
            app.CButton.Text = 'C';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'body_planes.jpg';
            app.directionEditFieldLabel = uilabel(app.UIFigure);
            app.directionEditFieldLabel.Text = 'direction';
            app.directionEditField = uieditfield(app.UIFigure, 'text');
            app.directionEditField.Value = '    T';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end